classdef IF4073_Tugas1_13519029_13519135 < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        ProcessedImageLabel      matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        SpecImageLabel           matlab.ui.control.Label
        specImg                  matlab.ui.control.Image
        BaseImageLabel           matlab.ui.control.Label
        baseImg                  matlab.ui.control.Image
        debugLabel               matlab.ui.control.Label
        ProcessButton            matlab.ui.control.Button
        pickSpecificationButton  matlab.ui.control.Button
        pickBaseButton           matlab.ui.control.Button
        aValLabel                matlab.ui.control.Label
        bValLabel                matlab.ui.control.Label
        gammaValLabel            matlab.ui.control.Label
        cValLabel                matlab.ui.control.Label
        creatorLabel             matlab.ui.control.Label
        valueSlider              matlab.ui.control.Slider
        valueSliderLabel         matlab.ui.control.Label
        cvalueSlider             matlab.ui.control.Slider
        cvalueSliderLabel        matlab.ui.control.Label
        bvalueSlider             matlab.ui.control.Slider
        bvalueLabel              matlab.ui.control.Label
        avalueSlider             matlab.ui.control.Slider
        avaluesliderLabel        matlab.ui.control.Label
        FunctionsDropDown        matlab.ui.control.DropDown
        FunctionsDropDownLabel   matlab.ui.control.Label
    end

    properties (Access = private)
        aVal double = 1; % 'a' Value
        bVal double = 1; % 'b' value
        cVal double = 1; % 'c' value
        gammaVal double = 1; % 'γ' value
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: avalueSlider
        function avalueSliderValueChanged(comp, event)
            value = comp.avalueSlider.Value;
            comp.aValLabel.Text = string(value);
            comp.aVal = value;
        end

        % Value changed function: bvalueSlider
        function bvalueSliderValueChanged(comp, event)
            value = comp.bvalueSlider.Value;
            comp.bValLabel.Text = string(value);
            comp.bVal = value;
        end

        % Value changed function: cvalueSlider
        function cvalueSliderValueChanged(comp, event)
            value = comp.cvalueSlider.Value;
            comp.cValLabel.Text = string(value);
            comp.cVal = value;
        end

        % Value changed function: valueSlider
        function valueSliderValueChanged(comp, event)
            value = comp.valueSlider.Value;
            comp.gammaValLabel.Text = string(value);
            comp.gammaVal = value;
        end

        % Button pushed function: pickBaseButton
        function pickBaseButtonPushed(comp, event)
            [base_file_name, base_file_path] = uigetfile({'*.tif;*.tiff;*.png;*.jpeg;*.jpg'}, 'Image Selector');
            baseImageVar = imread(strcat(base_file_path, base_file_name));
            [~, ~, dim] = size(baseImageVar);
            comp.baseImg.Visible = "on";
            if dim == 1, comp.baseImg.ImageSource = repmat(baseImageVar, 1, 1, 3); else, comp.baseImg.ImageSource = baseImageVar; end
        end

        % Button pushed function: pickSpecificationButton
        function pickSpecificationButtonPushed(comp, event)
            [spec_file_name, spec_file_path] = uigetfile({'*.tif;*.tiff;*.png;*.jpeg;*.jpg'}, 'Image Selector');
            specImageVar = imread(strcat(spec_file_path, spec_file_name));
            [~, ~, dim] = size(specImageVar);
            comp.specImg.Visible = "on";
            if dim == 1, comp.specImg.ImageSource = repmat(specImageVar, 1, 1, 3); else, comp.specImg.ImageSource = specImageVar; end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 1930 1091];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.FunctionsDropDownLabel = uilabel(comp);
            comp.FunctionsDropDownLabel.Text = 'Functions';
            comp.FunctionsDropDown = uidropdown(comp);
            comp.FunctionsDropDown.Items = {'Show Histogram', 'Image Brightening', 'Log Transformation', 'Power Transformation', 'Contrast Stretching', 'Histogram Equalization', 'Histogram Specification'};
            comp.FunctionsDropDown.Value = 'Show Histogram';
            comp.avaluesliderLabel = uilabel(comp);
            comp.avaluesliderLabel.Text = '''a'' value';
            comp.avalueSlider = uislider(comp);
            comp.avalueSlider.Limits = [0 10];
            comp.avalueSlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @avalueSliderValueChanged, true);
            comp.avalueSlider.Value = 1;
            comp.bvalueLabel = uilabel(comp);
            comp.bvalueLabel.Text = '''b'' value';
            comp.bvalueSlider = uislider(comp);
            comp.bvalueSlider.Limits = [0 10];
            comp.bvalueSlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @bvalueSliderValueChanged, true);
            comp.bvalueSlider.Value = 1;
            comp.cvalueSliderLabel = uilabel(comp);
            comp.cvalueSliderLabel.Text = '''c'' value';
            comp.cvalueSlider = uislider(comp);
            comp.cvalueSlider.Limits = [0 10];
            comp.cvalueSlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @cvalueSliderValueChanged, true);
            comp.cvalueSlider.Value = 1;
            comp.valueSliderLabel = uilabel(comp);
            comp.valueSliderLabel.Text = '''γ'' value';
            comp.valueSlider = uislider(comp);
            comp.valueSlider.Limits = [0 10];
            comp.valueSlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueSliderValueChanged, true);
            comp.valueSlider.Value = 1;
            comp.creatorLabel = uilabel(comp);
            comp.creatorLabel.Text = 'by 13519029 - Nicholas Chen & 13519135 - Naufal Alexander Suryasumirat';
            comp.cValLabel = uilabel(comp);
            comp.cValLabel.Text = '1';
            comp.gammaValLabel = uilabel(comp);
            comp.gammaValLabel.Text = '1';
            comp.bValLabel = uilabel(comp);
            comp.bValLabel.Text = '1';
            comp.aValLabel = uilabel(comp);
            comp.aValLabel.Text = '1';
            comp.pickBaseButton = uibutton(comp, 'push');
            comp.pickBaseButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @pickBaseButtonPushed, true);
            comp.pickBaseButton.Text = 'Pick Base Image';
            comp.pickSpecificationButton = uibutton(comp, 'push');
            comp.pickSpecificationButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @pickSpecificationButtonPushed, true);
            comp.pickSpecificationButton.Text = 'Pick Spec Image';
            comp.ProcessButton = uibutton(comp, 'push');
            comp.ProcessButton.Text = 'Process';
            comp.debugLabel = uilabel(comp);
            comp.baseImg = uiimage(comp);
            comp.baseImg.Visible = 'off';
            comp.BaseImageLabel = uilabel(comp);
            comp.BaseImageLabel.Text = 'Base Image';
            comp.specImg = uiimage(comp);
            comp.specImg.Visible = 'off';
            comp.SpecImageLabel = uilabel(comp);
            comp.SpecImageLabel.Text = 'Spec Image';
            comp.Image = uiimage(comp);
            comp.Image.Visible = 'off';
            comp.ProcessedImageLabel = uilabel(comp);
            comp.ProcessedImageLabel.Text = 'Processed Image';
        end
    end
end